classdef CarbideAnalyisFinalRev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        KHElementPanel                  matlab.ui.container.Panel
        ApplyButton_4                   matlab.ui.control.Button
        EdgeofElementKHEditField        matlab.ui.control.NumericEditField
        EdgeofElementKHEditFieldLabel   matlab.ui.control.Label
        ElementwithKHEditField          matlab.ui.control.NumericEditField
        ElementwithKHEditFieldLabel     matlab.ui.control.Label
        NodalTemperature                matlab.ui.control.Table
        DisplayNodeTempValuesButton     matlab.ui.control.Button
        InsertFileNameButton            matlab.ui.control.Button
        InsertFileNameEditField         matlab.ui.control.EditField
        ForcesPanel3D                   matlab.ui.container.Panel
        MagnitudeEditField              matlab.ui.control.NumericEditField
        MagnitudeEditField_2Label       matlab.ui.control.Label
        ApplyButton_3                   matlab.ui.control.Button
        NodeNumberEditFieldForces       matlab.ui.control.NumericEditField
        NodeNumberEditField_5Label      matlab.ui.control.Label
        NodesInformationPanel           matlab.ui.container.Panel
        GenerateNodeButton              matlab.ui.control.Button
        NodeNumberEditFieldNodes        matlab.ui.control.NumericEditField
        NodeNumberEditField_4Label      matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditField_2Label     matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditField_2Label     matlab.ui.control.Label
        DisplayTable                    matlab.ui.control.Table
        SolveButton                     matlab.ui.control.Button
        StructureInformationPanel       matlab.ui.container.Panel
        HeatLosshEditField              matlab.ui.control.NumericEditField
        HeatLosshEditFieldLabel         matlab.ui.control.Label
        ThermalConductivityKEditField   matlab.ui.control.NumericEditField
        ThermalConductivityKEditFieldLabel  matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        SaveInformationButton           matlab.ui.control.Button
        NumberofNDUEditField            matlab.ui.control.NumericEditField
        NumberofNDUEditFieldLabel       matlab.ui.control.Label
        HeatSourceQEditField            matlab.ui.control.NumericEditField
        HeatSourceQEditFieldLabel       matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        ElementInformationPanel         matlab.ui.container.Panel
        EndNode3EditField               matlab.ui.control.NumericEditField
        EndNode3EditFieldLabel          matlab.ui.control.Label
        EndNode2EditField               matlab.ui.control.NumericEditField
        EndNode2EditFieldLabel          matlab.ui.control.Label
        EndNode1EditField               matlab.ui.control.NumericEditField
        EndNode1EditFieldLabel          matlab.ui.control.Label
        ElementNumberEditField          matlab.ui.control.NumericEditField
        ElementNumberEditFieldLabel     matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        UploadDataButton                matlab.ui.control.Button
        CustomPointsButton              matlab.ui.control.Button
        SteadyStateTempDistribution     matlab.ui.control.UIAxes
        StructurePlot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property; % Description
        filename;
        data;
        num;
        n_element;
        n_nodes;
        ncon;
        X;
        Y;
        Q;
        h;
        ElementwithKH;
        ElementofElementKH;
        thickness;
        ForceVector;
        K;
        KE;
        n_ndu;
        NDU;
        boundarytemp;
        thermalconductivity;
        TableRead;
        n1;
        n2;
        n3;
        x1;
        x2;
        x3;
        y1;
        y2;
        y3;
        b1;
        b2;
        b3;
        c1;
        c2;
        c3;
        bmatrix;
        dmatrix;
        a;
        f;
        nodal_temperatures;
        node_number;
        element_number;
        counter;
        ncon1;
        ncon2;
        ncon3;
        magnitude;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Start Up Function

            app.StructureInformationPanel.Visible = 'on'; %Truss Information Panel Turned Off at Start
            app.ForcesPanel3D.Visible = 'on';
            app.ElementInformationPanel.Visible = 'on'; %Elements Panel Turned Off at Start
            app.NodesInformationPanel.Visible = 'on';
            app.SolveButton.Visible = 'on';
            app.CustomPointsButton.BackgroundColor = 'y';
            app.UploadDataButton.BackgroundColor = 'y';
            app.counter = 0;

        end

        % Button pushed function: CustomPointsButton
        function CustomPointsButtonPushed(app, event)
            %Custom Points Button
            app.CustomPointsButton.BackgroundColor = 'g';
            app.StructureInformationPanel.BackgroundColor = 'y';
            app.UploadDataButton.BackgroundColor = [.96 .96 .96];
      
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            %Generate Element Button
 
            %Retrieving Values from Panel
            app.element_number = app.ElementNumberEditField.Value;
            app.ncon1 = app.EndNode1EditField.Value;
            app.ncon2 = app.EndNode2EditField.Value;
            app.ncon3 = app.EndNode3EditField.Value;

            %Putting Element Information into data Matrix
            app.data(app.element_number,3) = app.ncon1;
            app.data(app.element_number,4) = app.ncon2;
            app.data(app.element_number,5) = app.ncon3;

            %Display Elements on DisplayTable
            app.DisplayTable.Data(app.element_number,3) = {app.ncon1};
            app.DisplayTable.Data(app.element_number,4) = {app.ncon2};
            app.DisplayTable.Data(app.element_number,5) = {app.ncon3};

            %Setting Fields Back to 0
            app.EndNode1EditField.Value = 0;
            app.EndNode2EditField.Value = 0;
            app.EndNode3EditField.Value = 0;
            app.ElementNumberEditField.Value = 0;
            

            %Background

                if app.element_number == app.n_element
                    app.ElementInformationPanel.BackgroundColor = 'g';
                    app.ForcesPanel3D.BackgroundColor = 'y';          

                end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
        app.SolveButton.BackgroundColor = 'g';
        app.n_element = app.data(1,1);
        app.n_nodes = app.data(1,2);
        app.ncon = [app.data(:,3),app.data(:,4),app.data(:,5)];
        app.X = app.data(:,6);
        app.Y = app.data(:,7);
        app.h = app.data(1,8);
        app.Q = app.data(1,9);
        app.thermalconductivity = app.data(1,10);
        app.thickness = app.data(1,11);
        app.n_ndu = app.data(1,12);
        app.NDU = [app.data(:,13)];
        app.boundarytemp = [app.data(:,14)];
        app.ElementwithKH = app.data(1,15);
        app.ElementofElementKH = app.data(:,16);            
        app.ForceVector = [zeros(app.n_nodes,1)]; 

        if app.ElementwithKH >=1
        % Plot the structure
        for i = 1:app.n_element
            app.n1 = app.ncon(i,1);
            app.n2 = app.ncon(i,2);
            app.n3 = app.ncon(i,3);
            app.x1 = app.X(app.n1);
            app.x2 = app.X(app.n2);
            app.x3 = app.X(app.n3);

            app.y1 = app.Y(app.n1);
            app.y2 = app.Y(app.n2);
            app.y3 = app.Y(app.n3);
            plot(app.StructurePlot,[app.x1,app.x2,app.x3,app.x1],[app.y1,app.y2,app.y3,app.y1],'LineWidth',1);
            plot(app.StructurePlot,[app.x1,app.x2,app.x3],[app.y1,app.y2,app.y3],'ro');
            hold(app.StructurePlot,'on');
        end

        % Initiate matrices
        app.K = zeros(app.n_nodes); % Global conduction matrix
        app.ForceVector = [zeros(app.n_nodes,1)];

        % Pre-processing: Assemble the global stiffness matrix
        for i = 1:app.n_element
            % Evaluate elemental stiffness matrices
            app.n1 = app.ncon(i,1);
            app.n2 = app.ncon(i,2);
            app.n3 = app.ncon(i,3);
            app.x1 = app.X(app.n1);
            app.x2 = app.X(app.n2);
            app.x3 = app.X(app.n3);
            app.y1 = app.Y(app.n1);
            app.y2 = app.Y(app.n2);
            app.y3 = app.Y(app.n3);

            app.b1 = app.y2 - app.y3;
            app.b2 = app.y3 - app.y1;
            app.b3 = app.y1 - app.y2;
            app.c1 = app.x3 - app.x2;
            app.c2 = app.x1 - app.x3;
            app.c3 = app.x2 - app.x1;

            app.bmatrix = [app.b1 app.b2 app.b3; app.c1 app.c2 app.c3];

            app.a = abs(det([1 app.x1 app.y1; 1 app.x2 app.y2; 1 app.x3 app.y3])) / 2; % Area of the triangle

            % Assume a simple isotropic material property matrix D
            app.dmatrix = app.thermalconductivity * eye(2);
            KC = (1 / (4 * app.a)) * transpose(app.bmatrix) * app.dmatrix * app.bmatrix;

            % Initialize the total element stiffness matrix as KC
            app.KE = KC;

            % Apply KH and Fh if the current element matches the specified element
            if ismember(i, app.ElementwithKH)
                % Get the specific edge where KH should be applied
                edge = app.ElementofElementKH(app.ElementwithKH == i);

                % Calculate lengths of edges
                L12 = sqrt((app.x2 - app.x1)^2 + (app.y2 - app.y1)^2);
                L23 = sqrt((app.x3 - app.x2)^2 + (app.y3 - app.y2)^2);
                L31 = sqrt((app.x1 - app.x3)^2 + (app.y1 - app.y3)^2);

                % Convective heat transfer stiffness matrix KH and Fh
                if edge == 1
                    % For edge (L1, L2)
                    KH1 = ((app.h * app.thickness * L12) / 6) * [2 1 0; 1 2 0; 0 0 0];
                    Fh1 = ((app.h * app.Q * L12 * app.thickness) / 2) * [1; 1; 0];
                    app.KE = app.KE + KH1;
                    app.ForceVector(app.n1) = app.ForceVector(app.n1) + Fh1(1);
                    app.ForceVector(app.n2) = app.ForceVector(app.n2) + Fh1(2);
                elseif edge == 2
                    % For edge (L2, L3)
                    KH2 = ((app.h * app.thickness * L23) / 6) * [0 0 0; 0 2 1; 0 1 2];
                    Fh2 = ((app.h * app.Q * L23 * app.thickness) / 2) * [0; 1; 1];
                    app.KE = app.KE + KH2;
                    app.ForceVector(app.n2) = app.ForceVector(app.n2) + Fh2(2);
                    app.ForceVector(app.n3) = app.ForceVector(app.n3) + Fh2(3);
                elseif edge == 3
                    % For edge (L3, L1)
                    KH3 = ((app.h * app.thickness * L31) / 6) * [2 0 1; 0 0 0; 1 0 2];
                    Fh3 = ((app.h * app.Q * L31 * app.thickness) / 2) * [1; 0; 1];
                    app.KE = app.KE + KH3;
                    app.ForceVector(app.n1) = app.ForceVector(app.n1) + Fh3(1);
                    app.ForceVector(app.n3) = app.ForceVector(app.n3) + Fh3(3);
                end
            end

            % Assemble KE into global matrix K
            element_nodes = [app.n1, app.n2, app.n3];
            for ii = 1:3
                for jj = 1:3
                    app.K(element_nodes(ii), element_nodes(jj)) = app.K(element_nodes(ii), element_nodes(jj)) + app.KE(ii, jj);
                end
            end
        end


        % Apply boundary conditions
        for i = 1:app.n_ndu
            node = app.NDU(i,1);
            temp = app.boundarytemp(i,1);

            % Update the force vector for boundary conditions
            app.ForceVector(node) = temp;
            for j = 1:app.n_nodes
                if j ~= node
                    app.ForceVector(j) = app.ForceVector(j) + abs(app.K(j, node)) * temp;
                end
            end

            % Modify the global stiffness matrix
            app.K(node, :) = 0;
            app.K(:, node) = 0;
            app.K(node, node) = 1;
        end

        % Final Answer
        app.nodal_temperatures = app.K \ app.ForceVector;
        dlmwrite('NodalTemperatures.txt',app.nodal_temperatures, '');


        % Plot the structure with temperature gradient
        for i = 1:app.n_element
            app.n1 = app.ncon(i,1);
            app.n2 = app.ncon(i,2);
            app.n3 = app.ncon(i,3);

            x = [app.X(app.n1), app.X(app.n2), app.X(app.n3), app.X(app.n1)];
            y = [app.Y(app.n1), app.Y(app.n2), app.Y(app.n3), app.Y(app.n1)];
            fill(app.SteadyStateTempDistribution,x, y, [app.nodal_temperatures(app.n1), app.nodal_temperatures(app.n2), app.nodal_temperatures(app.n3), app.nodal_temperatures(app.n1)]);
            hold(app.SteadyStateTempDistribution,'on');
            plot(app.SteadyStateTempDistribution,x, y, 'k-'); % Outline of the element
            colorbar;
        end
        end


        if app.ElementwithKH ==0
        % Plot the structure
        for i = 1:app.n_element

            app.n1 = app.ncon(i,1);
            app.n2 = app.ncon(i,2);
            app.n3 = app.ncon(i,3);
            app.x1 = app.X(app.n1);
            app.x2 = app.X(app.n2);
            app.x3 = app.X(app.n3);

            app.y1 = app.Y(app.n1);
            app.y2 = app.Y(app.n2);
            app.y3 = app.Y(app.n3);
            plot(app.StructurePlot,[app.x1,app.x2,app.x3,app.x1],[app.y1,app.y2,app.y3,app.y1],'LineWidth',1);
            plot(app.StructurePlot,[app.x1,app.x2,app.x3],[app.y1,app.y2,app.y3],'ro');
            hold(app.StructurePlot,'on');

        end

        % Initiate matrices
        app.K = zeros(app.n_nodes); % Global conduction matrix
        app.ForceVector = [zeros(app.n_nodes,1)];

        % Pre-processing: Assemble the global stiffness matrix
        for i = 1:app.n_element
            % Evaluate elemental stiffness matrices
            app.n1 = app.ncon(i,1);
            app.n2 = app.ncon(i,2);
            app.n3 = app.ncon(i,3);
            app.x1 = app.X(app.n1);
            app.x2 = app.X(app.n2);
            app.x3 = app.X(app.n3);
            app.y1 = app.Y(app.n1);
            app.y2 = app.Y(app.n2);
            app.y3 = app.Y(app.n3);

            app.b1 = app.y2 - app.y3;
            app.b2 = app.y3 - app.y1;
            app.b3 = app.y1 - app.y2;
            app.c1 = app.x3 - app.x2;
            app.c2 = app.x1 - app.x3;
            app.c3 = app.x2 - app.x1;

            app.bmatrix = [app.b1 app.b2 app.b3; app.c1 app.c2 app.c3];

            app.a = abs(det([1 app.x1 app.y1; 1 app.x2 app.y2; 1 app.x3 app.y3])) / 2; % Area of the triangle

            % Local force vector for the element
            app.f = ((app.Q * app.a * app.thickness) / 3) * [1; 1; 1];

            % Assemble the global force vector
            app.ForceVector(app.n1) = app.ForceVector(app.n1) + app.f(1);
            app.ForceVector(app.n2) = app.ForceVector(app.n2) + app.f(2);
            app.ForceVector(app.n3) = app.ForceVector(app.n3) + app.f(3);        

            % Assume a simple isotropic material property matrix D
            app.dmatrix = app.thermalconductivity * eye(2);
            app.KE = (1 / (4 * app.a)) * transpose(app.bmatrix) * app.dmatrix * app.bmatrix;

            % Assemble KE into global matrix K
            element_nodes = [app.n1, app.n2, app.n3];
            for ii = 1:3
                for jj = 1:3
                    app.K(element_nodes(ii), element_nodes(jj)) = app.K(element_nodes(ii), element_nodes(jj)) + app.KE(ii, jj);
                end
            end
        end

        % Apply boundary conditions
        for i = 1:app.n_ndu
            node = app.NDU(i,1);
            temp = app.boundarytemp(i,1);

            % Update the force vector for boundary conditions
            app.ForceVector(node) = temp;
            for j = 1:app.n_nodes
                if j ~= node
                    app.ForceVector(j) = app.ForceVector(j) + abs(app.K(j, node)) * temp;
                end
            end

            % Modify the global stiffness matrix
            app.K(node, :) = 0;
            app.K(:, node) = 0;
            app.K(node, node) = 1;
        end

        %Final Answer
        app.nodal_temperatures = app.K\app.ForceVector;
        dlmwrite('NodalTemperatures.txt',app.nodal_temperatures, '');

        % Plot the structure with temperature gradient
        for i = 1:app.n_element
            app.n1 = app.ncon(i,1);
            app.n2 = app.ncon(i,2);
            app.n3 = app.ncon(i,3);

            x = [app.X(app.n1), app.X(app.n2), app.X(app.n3), app.X(app.n1)];
            y = [app.Y(app.n1), app.Y(app.n2), app.Y(app.n3), app.Y(app.n1)];
            fill(app.SteadyStateTempDistribution,x, y, [app.nodal_temperatures(app.n1), app.nodal_temperatures(app.n2), app.nodal_temperatures(app.n3), app.nodal_temperatures(app.n1)]);
            hold(app.SteadyStateTempDistribution,'on');
            plot(app.SteadyStateTempDistribution,x, y, 'k-'); % Outline of the element
            colorbar;
        end
        end
        

        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            app.UploadDataButton.BackgroundColor = 'g';
            app.CustomPointsButton.BackgroundColor = [.96 .96 .96];
            app.InsertFileNameButton.BackgroundColor = 'y';
            
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)


            %Retrieving Variables From Fields
            app.node_number = app.NodeNumberEditFieldForces.Value;
            app.magnitude = app.MagnitudeEditField.Value;
            app.counter = app.counter + 1;
            app.data(app.counter,13) = app.node_number;
            app.data(app.counter,14) = app.magnitude;


            %Setting Fields Back to 0    
            app.NodeNumberEditFieldForces.Value = 0;
            app.MagnitudeEditField.Value = 0;
            app.DisplayTable.Data(app.counter,13) = {app.node_number};
            app.DisplayTable.Data(app.counter,14) = {app.magnitude};


            %Background
                if app.counter == app.n_ndu
                    app.ForcesPanel3D.BackgroundColor = 'g';
                    app.KHElementPanel.BackgroundColor = 'y';
                end
        end

        % Button pushed function: InsertFileNameButton
        function InsertFileNameButtonPushed(app, event)
            app.filename = app.InsertFileNameEditField.Value;
            app.InsertFileNameButton.BackgroundColor = 'g';
            [num,txt,raw] = xlsread(app.filename);
            app.data = num;
            app.n_element = app.data(1,1);
            app.n_nodes = app.data(1,2);
            app.ncon = [app.data(:,3),app.data(:,4),app.data(:,5)];
            app.X = app.data(:,6);
            app.Y = app.data(:,7);
            app.h = app.data(1,8);
            app.Q = app.data(1,9);
            app.thermalconductivity = app.data(1,10);
            app.thickness = app.data(1,11);
            app.n_ndu = app.data(1,12);
            app.NDU = [app.data(:,13)];
            app.boundarytemp = [app.data(:,14)];
            app.ElementwithKH = app.data(:,15);
            app.ElementofElementKH = app.data(:,16);            
            app.ForceVector = [zeros(app.n_nodes,1)];    
            app.TableRead = readtable(app.filename);
            app.DisplayTable.Data= app.TableRead;
            app.SolveButton.BackgroundColor = 'y';
        end

        % Button pushed function: DisplayNodeTempValuesButton
        function DisplayNodeTempValuesButtonPushed(app, event)
            app.DisplayNodeTempValuesButton.BackgroundColor = 'g';
                    app.TableRead = importdata('NodalTemperatures.txt') ; 
                    app.NodalTemperature.Data(:,1) = app.TableRead;
        end

        % Button pushed function: SaveInformationButton
        function SaveInformationButtonPushed(app, event)
            %Save Information Button
            app.StructureInformationPanel.BackgroundColor = 'g';
            app.NodesInformationPanel.BackgroundColor = 'y';

            %Retrieving Values From Text Fields
            app.n_element = app.NumberofElementsEditField.Value;
            app.n_nodes = app.NumberofNodesEditField.Value;
            app.n_ndu = app.NumberofNDUEditField.Value;
            app.h = app.HeatLosshEditField.Value
            app.Q = app.HeatSourceQEditField.Value;
            app.thermalconductivity = app.ThermalConductivityKEditField.Value;
            app.thickness = app.ThicknessEditField.Value;

            %Intializing data Matrix
            app.data = zeros((app.n_nodes*2),16);

            %Inserting Retireved Values into data Matrix 
            app.data(1,1) = app.n_element;
            app.data(1,2) = app.n_nodes;
            app.data(1,8) = app.h;
            app.data(1,9) = app.Q;
            app.data(1,10) = app.thermalconductivity;
            app.data(1,11) = app.thickness;
            app.data(1,12) = app.n_ndu;
                       
            
            %Inserting Data to DisplayTable
            app.DisplayTable.Data = cell(app.data(1,2)*2,16);
            app.DisplayTable.Data(1,1) = {app.n_element};
            app.DisplayTable.Data(1,2) = {app.n_nodes};
            app.DisplayTable.Data(1,8) = {app.h};
            app.DisplayTable.Data(1,9) = {app.Q};
            app.DisplayTable.Data(1,10) = {app.thermalconductivity};
            app.DisplayTable.Data(1,11) = {app.thickness};
            app.DisplayTable.Data(1,12) = {app.n_ndu};


            %Reset Values to 0
            app.NumberofElementsEditField.Value = 0;
            app.NumberofNodesEditField.Value = 0;
            app.NumberofNDUEditField.Value = 0; 
            app.HeatSourceQEditField.Value = 0;
            app.ThicknessEditField.Value = 0;
            app.ThermalConductivityKEditField.Value = 0;
            
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            %Generate Node Button
            
            %Setting Variables
            app.node_number = app.NodeNumberEditFieldNodes.Value;

            %Retrieving X and Y Coordinates of Nodes
            app.X = app.XCoordinateEditField.Value;
            app.Y = app.YCoordinateEditField.Value;

            %Putting in X and Y Coordinates of Nodes into data Matrix
            app.data(app.node_number,6) = app.X;
            app.data(app.node_number,7) = app.Y;

            %Display Node Coordinates in Display Table
            app.DisplayTable.Data(app.node_number,6) = {app.X};
            app.DisplayTable.Data(app.node_number,7) = {app.Y};

            %Setting Fields Back to 0
            app.XCoordinateEditField.Value = 0;
            app.YCoordinateEditField.Value = 0;
            app.NodeNumberEditFieldNodes.Value = 0;

            %Background
            if app.node_number == app.n_nodes
                app.NodesInformationPanel.BackgroundColor = 'g';
                app.ElementInformationPanel.BackgroundColor = 'y';
            end
        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            app.ElementwithKH = app.ElementwithKHEditField.Value;
            app.ElementofElementKH = app.EdgeofElementKHEditField.Value;
            app.data(1,15) = app.ElementwithKH;
            app.data(1,16) = app.ElementofElementKH;

            %Setting Fields Back to 0    
            app.ElementwithKHEditField.Value = 0;
            app.EdgeofElementKHEditField.Value = 0;
            app.DisplayTable.Data(1,15) = {app.node_number};
            app.DisplayTable.Data(1,16) = {app.magnitude};
            app.KHElementPanel.BackgroundColor = 'g';
            app.SolveButton.BackgroundColor = 'y';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StructurePlot = uiaxes(app.GridLayout);
            title(app.StructurePlot, 'Structure Plot')
            xlabel(app.StructurePlot, 'X')
            ylabel(app.StructurePlot, 'Y')
            zlabel(app.StructurePlot, 'Z')
            app.SteadyStateTempDistribution = uiaxes(app.GridLayout);
            title(app.SteadyStateTempDistribution, 'Steady-State Temperature Distribution')
            xlabel(app.SteadyStateTempDistribution, 'X')
            ylabel(app.SteadyStateTempDistribution, 'Y')
            zlabel(app.SteadyStateTempDistribution, 'Z')
            app.CustomPointsButton = uibutton(app.GridLayout, 'push');
            app.CustomPointsButton.ButtonPushedFcn = createCallbackFcn(app, @CustomPointsButtonPushed, true);
            app.CustomPointsButton.Text = 'Custom Points';
            app.UploadDataButton = uibutton(app.GridLayout, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.ElementInformationPanel = uipanel(app.GridLayout);
            app.ElementInformationPanel.Title = 'Element Information';
            app.GenerateElementButton = uibutton(app.ElementInformationPanel, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'Generate Element';
            app.ElementNumberEditFieldLabel = uilabel(app.ElementInformationPanel);
            app.ElementNumberEditFieldLabel.Text = 'Element Number';
            app.ElementNumberEditField = uieditfield(app.ElementInformationPanel, 'numeric');
            app.EndNode1EditFieldLabel = uilabel(app.ElementInformationPanel);
            app.EndNode1EditFieldLabel.Text = 'End Node 1';
            app.EndNode1EditField = uieditfield(app.ElementInformationPanel, 'numeric');
            app.EndNode2EditFieldLabel = uilabel(app.ElementInformationPanel);
            app.EndNode2EditFieldLabel.Text = 'End Node 2';
            app.EndNode2EditField = uieditfield(app.ElementInformationPanel, 'numeric');
            app.EndNode3EditFieldLabel = uilabel(app.ElementInformationPanel);
            app.EndNode3EditFieldLabel.Text = 'End Node 3';
            app.EndNode3EditField = uieditfield(app.ElementInformationPanel, 'numeric');
            app.StructureInformationPanel = uipanel(app.GridLayout);
            app.StructureInformationPanel.Title = 'Structure Information';
            app.NumberofElementsEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.NumberofNodesEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.HeatSourceQEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.HeatSourceQEditFieldLabel.Text = 'Heat Source = Q';
            app.HeatSourceQEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.NumberofNDUEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.NumberofNDUEditFieldLabel.Text = 'Number of NDU';
            app.NumberofNDUEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.SaveInformationButton = uibutton(app.StructureInformationPanel, 'push');
            app.SaveInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInformationButtonPushed, true);
            app.SaveInformationButton.Text = 'Save Information';
            app.ThicknessEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.ThermalConductivityKEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.ThermalConductivityKEditFieldLabel.Text = 'Thermal Conductivity = K';
            app.ThermalConductivityKEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.HeatLosshEditFieldLabel = uilabel(app.StructureInformationPanel);
            app.HeatLosshEditFieldLabel.Text = 'Heat Loss = h';
            app.HeatLosshEditField = uieditfield(app.StructureInformationPanel, 'numeric');
            app.SolveButton = uibutton(app.GridLayout, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.DisplayTable = uitable(app.GridLayout);
            app.DisplayTable.ColumnName = {'Number of Elements'; 'Number of Nodes'; 'Ncon1'; 'Ncon2'; 'Ncon3'; 'X'; 'Y'; 'h'; 'Q'; 'Themal Conductivity'; 'Thickness'; 'Number of NDU'; 'NDU'; 'Boundary Temp'; 'Element with KH'; 'Element of Element KH'};
            app.DisplayTable.RowName = {};
            app.NodesInformationPanel = uipanel(app.GridLayout);
            app.NodesInformationPanel.Title = 'Node Information';
            app.XCoordinateEditField_2Label = uilabel(app.NodesInformationPanel);
            app.XCoordinateEditField_2Label.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.NodesInformationPanel, 'numeric');
            app.YCoordinateEditField_2Label = uilabel(app.NodesInformationPanel);
            app.YCoordinateEditField_2Label.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.NodesInformationPanel, 'numeric');
            app.NodeNumberEditField_4Label = uilabel(app.NodesInformationPanel);
            app.NodeNumberEditField_4Label.Text = 'Node Number';
            app.NodeNumberEditFieldNodes = uieditfield(app.NodesInformationPanel, 'numeric');
            app.GenerateNodeButton = uibutton(app.NodesInformationPanel, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = 'Generate Node';
            app.ForcesPanel3D = uipanel(app.GridLayout);
            app.ForcesPanel3D.Title = 'Boundary Condition';
            app.NodeNumberEditField_5Label = uilabel(app.ForcesPanel3D);
            app.NodeNumberEditField_5Label.Text = 'Node Number';
            app.NodeNumberEditFieldForces = uieditfield(app.ForcesPanel3D, 'numeric');
            app.ApplyButton_3 = uibutton(app.ForcesPanel3D, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.MagnitudeEditField_2Label = uilabel(app.ForcesPanel3D);
            app.MagnitudeEditField_2Label.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.ForcesPanel3D, 'numeric');
            app.InsertFileNameEditField = uieditfield(app.GridLayout, 'text');
            app.InsertFileNameButton = uibutton(app.GridLayout, 'push');
            app.InsertFileNameButton.ButtonPushedFcn = createCallbackFcn(app, @InsertFileNameButtonPushed, true);
            app.InsertFileNameButton.Text = 'Insert File Name';
            app.DisplayNodeTempValuesButton = uibutton(app.GridLayout, 'push');
            app.DisplayNodeTempValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayNodeTempValuesButtonPushed, true);
            app.DisplayNodeTempValuesButton.Text = 'Display Nodal Temperatures';
            app.NodalTemperature = uitable(app.GridLayout);
            app.NodalTemperature.ColumnName = {'Nodal Temperature'};
            app.NodalTemperature.RowName = {};
            app.KHElementPanel = uipanel(app.GridLayout);
            app.KHElementPanel.Title = 'KH Element';
            app.ElementwithKHEditFieldLabel = uilabel(app.KHElementPanel);
            app.ElementwithKHEditFieldLabel.Text = 'Element with KH';
            app.ElementwithKHEditField = uieditfield(app.KHElementPanel, 'numeric');
            app.EdgeofElementKHEditFieldLabel = uilabel(app.KHElementPanel);
            app.EdgeofElementKHEditFieldLabel.Text = 'Edge of Element KH';
            app.EdgeofElementKHEditField = uieditfield(app.KHElementPanel, 'numeric');
            app.ApplyButton_4 = uibutton(app.KHElementPanel, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarbideAnalyisFinalRev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end